classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HeavytrafficLabel             matlab.ui.control.Label
        ModeratetrafficLabel          matlab.ui.control.Label
        LighttrafficLabel             matlab.ui.control.Label
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        errorMessages_TextArea        matlab.ui.control.TextArea
        ErrormessagesTextAreaLabel    matlab.ui.control.Label
        standstillTime_Field          matlab.ui.control.NumericEditField
        AveragespeedkmhLabel_3        matlab.ui.control.Label
        vechicleCount_Field           matlab.ui.control.NumericEditField
        AveragespeedkmhLabel_2        matlab.ui.control.Label
        averageSpeed_Field            matlab.ui.control.NumericEditField
        AveragespeedkmhLabel          matlab.ui.control.Label
        statistics_Label              matlab.ui.control.Label
        slider_Label                  matlab.ui.control.Label
        chooseDate_DropDown           matlab.ui.control.DropDown
        chooseDate_Label              matlab.ui.control.Label
        roadSelection_DropDown        matlab.ui.control.DropDown
        roadSelection_Label           matlab.ui.control.Label
        chooseTickrate_DropDown       matlab.ui.control.DropDown
        chooseTickrate_Label          matlab.ui.control.Label
        legend_Label                  matlab.ui.control.Label
        selectTime_Slider             matlab.ui.control.Slider
        generateVisualization_Button  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    % global variables
    properties (Access = public)
        currentRoad; % road name
        currentTime; % two value - beginning and end global time
        currentTickrate; % how many part 900 seconds gets divided into
        currentSliderChoice; % currennt selected value on the slider
        roadColor;
    end
    
    methods (Access = public)
                
        function plotRoad(app)
            apiErrorOccured = false;
            apiErrorMessage = '';
            cla(app.UIAxes);


            % Switch based on app.currentRoad value
            switch app.currentRoad
                case 'i-80'
                    shpFile = 'emeryville.shp';
                    
                    shpFile2 = 'emeryville-camera-coverage.shp';
                    

                    %shpFile3 = 'signs-and-signals.shp';
                case 'lankershim'
                    
                    shpFile = 'LA-UniversalCity.shp';
                    
                    shpFile2 = 'lankershim-camera-coverage.shp';
                    
                    
                case 'us-101'
                    shpFile = 'US-101.shp';
                    
                    shpFile2 = 'us-101-camera-coverage.shp';
                    
                otherwise
                    error('Invalid road selection');
            end
            
            % Load the shapefile
            shp = shaperead(shpFile);
            
            
            %shp3 = shaperead(shpFile3);
            
            % Extract coordinates
            lon = [shp.X];
            lat = [shp.Y];
            
            %lon3 = [shp3.X];
            %lat3 = [shp3.Y];
            
            % Plot the shapefile on the app.UIAxes
            plot(app.UIAxes, lon, lat, '-b', 'LineWidth', 1);
            hold(app.UIAxes,'on');
            try
            shp2 = shaperead(shpFile2);
            catch
                apiErrorOccured = true;
                apiErrorMessage = [apiErrorMessage, 'Error occured while getting camera coverage; '];
            end
            if(apiErrorOccured == false)
            
            lon2 = [shp2.X];
            lat2 = [shp2.Y];
            plot(app.UIAxes, lon2, lat2, '-b', 'LineWidth', 5,'Color',app.roadColor);
            end

            %plot(app.UIAxes, lon3, lat3, '-b', 'LineWidth', 3,'Color','yellow');
            
            % Customize the map
            grid(app.UIAxes, 'on');
            
            % Add title and labels if needed
            title(app.UIAxes, app.currentRoad);
            xlabel(app.UIAxes, 'Longitude');
            ylabel(app.UIAxes, 'Latitude');
            hold(app.UIAxes,'off');

          if(apiErrorOccured)
                app.errorMessages_TextArea.Enable = true;
                % disp(apiErrorMessage);
                app.errorMessages_TextArea.Value = apiErrorMessage;
            end 
           
        end


        function calculateStatistics(app)

            apiErrorOccured = false;
            apiErrorMessage = '';
            baseURL = 'https://data.transportation.gov/resource/8ect-6jqj.json';
            
            % Selecting location based on the slider
            locationParam = ['location=', app.currentRoad];
            
            % Selecting min and max global time based on the slider
            selectedInterval = 900/app.currentTickrate;
            minGlobalTime = app.currentTime(1) + (app.currentSliderChoice-selectedInterval)*1000;
            maxGlobalTime = minGlobalTime + (selectedInterval*1000);
            timeWhereClause = ['$where=global_time > ', num2str(minGlobalTime), ' AND global_time < ', num2str(maxGlobalTime)];
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken, 'Timeout', 10);
            
            % Counting unique cars
            countVehiclesURL  = [baseURL, '?$select=count(distinct vehicle_id)&', locationParam, '&', timeWhereClause];
            % disp(countVehiclesURL); 
            try
                countVehicles = webread(countVehiclesURL, options);
                % disp(countVehicles.count_distinct_vehicle_id);
                app.vechicleCount_Field.Value = str2double(countVehicles.count_distinct_vehicle_id);
                app.vechicleCount_Field.Enable = true;
            catch
                apiErrorOccured = true;
                apiErrorMessage = [apiErrorMessage, 'Error occured while getting vehicle count from api; '];
            end
            
            % Average speed in km/hour (requires convertion from feet/s)
            averageSpeedURL = [baseURL, '?$select=avg(v_vel)&', locationParam, '&', timeWhereClause];
            % disp(averageSpeedURL);
            try
                averageSpeed = webread(averageSpeedURL, options);
                avg_v_vel_value = str2double(averageSpeed.avg_v_vel);
                averageSpeedInKm = avg_v_vel_value * 0.3048 * 3600 / 1000;
                % disp(averageSpeedInKm);
                if averageSpeedInKm >= 30
                    app.roadColor = [0, 1, 0];
                elseif averageSpeedInKm < 30 && averageSpeedInKm > 15
                    app.roadColor = [1, 0.647, 0];
                else
                    app.roadColor = [1, 0, 0];
                end
                app.averageSpeed_Field.Value = averageSpeedInKm;
                app.averageSpeed_Field.Enable = true;
            catch
                apiErrorOccured = true;
                apiErrorMessage = [apiErrorMessage, 'Error occured while getting average speed from api; '];
            end

            % Sum of all time spent in traffic (car speed equal to 0)
            trafficCountURL = [baseURL, '?$select=count(*)&', locationParam, '&', timeWhereClause, ' AND v_vel = 0'];
            % disp(trafficCountURL);
            try
                trafficCount = webread(trafficCountURL, options);
                trafficInSeconds = 10 * str2double(trafficCount.count);
                % disp(trafficInSeconds);
                app.standstillTime_Field.Value = trafficInSeconds;
                app.standstillTime_Field.Enable = true;
            catch
                apiErrorOccured = true;
                apiErrorMessage = [apiErrorMessage, 'Error occured while getting traffic time from api; '];
            end

            if(apiErrorOccured)
                app.errorMessages_TextArea.Enable = true;
                % disp(apiErrorMessage);
                app.errorMessages_TextArea.Value = apiErrorMessage;
            end 

            % Getting records directly
            % fullURL = [baseURL, '?', locationParam, '&', timeWhereClause];
            % disp(fullURL); % Display the URL (optional)
            % data = webread(fullURL, options);
            
            % drawing cars (global x and global y)
            % if ~isempty(data)
            %     % Extract relevant columns
            %     globalX = str2double({data.global_x});
            %     globalY = str2double({data.global_y});
            % 
            %     % Check for NaN values after conversion
            %     validIndices = ~isnan(globalX) & ~isnan(globalY);
            %     globalX = globalX(validIndices);
            %     globalY = globalY(validIndices);
            % 
            %     disp(num2str(length(globalY)));
            % 
            %     % Plot the data on app.UIAxes
            %     % plot(app.UIAxes, globalX, globalY, 'o');  % Change the plot type if needed
            %     % hold off;
            % else
            %     disp('No data retrieved from the API.');  % Display a message if data is empty
            % end

            % Counting all records in the given location and timeframe
            % countURL = [baseURL, '?$select=count(*)&', locationParam, '&', timeWhereClause];
            % disp(countURL);
            % countData = webread(countURL, options);
            % recordCount = countData.count;
            % disp(recordCount);

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.selectTime_Slider.Limits = [10, 900];
            quantizedValuesMajor = 900/15:900/15:900;
            app.selectTime_Slider.MajorTicks = quantizedValuesMajor;

            app.chooseDate_DropDown.Enable = false;
            app.chooseTickrate_DropDown.Enable = false;
            app.selectTime_Slider.Enable = false;
            app.generateVisualization_Button.Enable = false;
            
            app.standstillTime_Field.Enable = false;
            app.vechicleCount_Field.Enable = false;
            app.averageSpeed_Field.Enable = false;
            app.errorMessages_TextArea.Enable = false;
            
        end

        % Button pushed function: generateVisualization_Button
        function generateVisualization_ButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            tickString = app.chooseTickrate_DropDown.Value;
            tickValue = str2double(tickString);
            
            % saving values
            app.currentRoad = app.roadSelection_DropDown.Value;
            app.currentTime = app.chooseDate_DropDown.Value;
            app.currentTickrate = str2double(app.chooseTickrate_DropDown.Value);
            
            % disabling statisctics (enabled on succes)
            app.standstillTime_Field.Enable = false;
            app.vechicleCount_Field.Enable = false;
            app.averageSpeed_Field.Enable = false;

            % removing error message (if existed)
            app.errorMessages_TextArea.Value = '';
            app.errorMessages_TextArea.Enable = false;

            
            if tickValue == 1
                app.currentSliderChoice = 900;
                app.selectTime_Slider.Enable = false;
                
                calculateStatistics(app);
                plotRoad(app);
               
                
            else
                app.selectTime_Slider.Enable = true;
                
                % populating slider
                app.selectTime_Slider.Limits = [900/tickValue, 900];
                
                quantizedValues = 900/tickValue:900/tickValue:900;
                app.selectTime_Slider.MinorTicks = quantizedValues;
                
                quantizedValuesMajor = 900/15:900/15:900;
                app.selectTime_Slider.MajorTicks = quantizedValuesMajor;


                app.selectTime_Slider.Value = quantizedValues(1);
                app.currentSliderChoice = app.selectTime_Slider.Value;
                
                calculateStatistics(app);
                plotRoad(app);


            end
            
        end

        % Value changed function: selectTime_Slider
        function selectTime_SliderValueChanged(app, event)
            
            % removing error message (if present)
            app.errorMessages_TextArea.Value = '';
            app.errorMessages_TextArea.Enable = false;

            value = app.selectTime_Slider.Value;

            quantizedValues = app.selectTime_Slider.MinorTicks;
            roundedValue = round(value / (900/length(quantizedValues))) * (900/length(quantizedValues));
          
            app.selectTime_Slider.Value = roundedValue;
            app.currentSliderChoice = roundedValue;
            
            calculateStatistics(app);
            plotRoad(app);
            
            
        end

        % Value changed function: roadSelection_DropDown
        function roadSelection_DropDownValueChanged(app, event)
            
            value = app.roadSelection_DropDown.Value;
            app.chooseDate_DropDown.Enable = true;
            app.chooseTickrate_DropDown.Enable = true;
          
            switch value

                case "us-101"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                    ItemsData = {
                        [1118847000000, 1118847900000];
                        [1118847900000, 1118848800000];
                        [1118848800000, 1118849700000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                case "i-80"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                    ItemsData = {
                        [1113433200000, 1113434100000];
                        [1113436800000, 1113437700000];
                        [1113437700000, 1113438600000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                case "lankershim"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["8:30 - 8:45", "8:45 - 9:00"];
                    ItemsData = {
                        [1118935800000, 1118936700000];
                        [1118936700000, 1118937600000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                otherwise
                    app.generateVisualization_Button.Enable = false;
                    app.chooseDate_DropDown.Enable = false;
                    app.chooseTickrate_DropDown.Enable = false;        
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Traffic Visualization')
            zlabel(app.UIAxes, 'Z')
            app.generateVisualization_Button = uibutton(app.UIFigure, 'push');
            app.generateVisualization_Button.ButtonPushedFcn = createCallbackFcn(app, @generateVisualization_ButtonPushed, true);
            app.generateVisualization_Button.Text = 'Generate Visualization';
            app.selectTime_Slider = uislider(app.UIFigure);
            app.selectTime_Slider.MajorTicks = [];
            app.selectTime_Slider.ValueChangedFcn = createCallbackFcn(app, @selectTime_SliderValueChanged, true);
            app.selectTime_Slider.MinorTicks = [];
            app.legend_Label = uilabel(app.UIFigure);
            app.legend_Label.Text = 'Legend';
            app.chooseTickrate_Label = uilabel(app.UIFigure);
            app.chooseTickrate_Label.Text = 'Choose tickrate';
            app.chooseTickrate_DropDown = uidropdown(app.UIFigure);
            app.chooseTickrate_DropDown.Items = {'15 minutes', '60 seconds', '30 seconds', '10 seconds'};
            app.chooseTickrate_DropDown.ItemsData = {'1', '15', '30', '90'};
            app.chooseTickrate_DropDown.Value = '1';
            app.roadSelection_Label = uilabel(app.UIFigure);
            app.roadSelection_Label.Text = 'Choose road';
            app.roadSelection_DropDown = uidropdown(app.UIFigure);
            app.roadSelection_DropDown.Items = {'i-80', 'us-101', 'lankershim', '-'};
            app.roadSelection_DropDown.ValueChangedFcn = createCallbackFcn(app, @roadSelection_DropDownValueChanged, true);
            app.roadSelection_DropDown.Value = '-';
            app.chooseDate_Label = uilabel(app.UIFigure);
            app.chooseDate_Label.Text = 'Choose date';
            app.chooseDate_DropDown = uidropdown(app.UIFigure);
            app.chooseDate_DropDown.Items = {};
            app.chooseDate_DropDown.Placeholder = '-';
            app.chooseDate_DropDown.Value = {};
            app.slider_Label = uilabel(app.UIFigure);
            app.slider_Label.Text = '[seconds]';
            app.statistics_Label = uilabel(app.UIFigure);
            app.statistics_Label.Text = 'Statistics';
            app.AveragespeedkmhLabel = uilabel(app.UIFigure);
            app.AveragespeedkmhLabel.Text = {'Average speed'; '[km/h]'};
            app.averageSpeed_Field = uieditfield(app.UIFigure, 'numeric');
            app.averageSpeed_Field.AllowEmpty = 'on';
            app.averageSpeed_Field.Editable = 'off';
            app.averageSpeed_Field.Value = [];
            app.AveragespeedkmhLabel_2 = uilabel(app.UIFigure);
            app.AveragespeedkmhLabel_2.Text = {'Vehicle count'; '(unique)'};
            app.vechicleCount_Field = uieditfield(app.UIFigure, 'numeric');
            app.vechicleCount_Field.AllowEmpty = 'on';
            app.vechicleCount_Field.Editable = 'off';
            app.vechicleCount_Field.Value = [];
            app.AveragespeedkmhLabel_3 = uilabel(app.UIFigure);
            app.AveragespeedkmhLabel_3.Text = {'Standstill time'; '[seconds]'};
            app.standstillTime_Field = uieditfield(app.UIFigure, 'numeric');
            app.standstillTime_Field.AllowEmpty = 'on';
            app.standstillTime_Field.Editable = 'off';
            app.standstillTime_Field.Value = [];
            app.ErrormessagesTextAreaLabel = uilabel(app.UIFigure);
            app.ErrormessagesTextAreaLabel.Text = 'Error messages';
            app.errorMessages_TextArea = uitextarea(app.UIFigure);
            app.errorMessages_TextArea.Editable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.LighttrafficLabel = uilabel(app.UIFigure);
            app.LighttrafficLabel.Text = 'Light traffic';
            app.ModeratetrafficLabel = uilabel(app.UIFigure);
            app.ModeratetrafficLabel.Text = 'Moderate traffic';
            app.HeavytrafficLabel = uilabel(app.UIFigure);
            app.HeavytrafficLabel.Text = 'Heavy traffic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end